classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        drink_image   matlab.ui.control.Image
        arrow_r       matlab.ui.control.Button
        arrow_l       matlab.ui.control.Button
        end_prog      matlab.ui.control.Button
        mix           matlab.ui.control.Button
        ruleta        matlab.ui.control.Button
        personalised  matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    
    properties (Access = public)
         current_drink = "Cuba_libre" % Holds the info about the currently selected drink
         drinks = ["Cuba_libre", "Gin_tonic", "Vodka_dzus"] % Vector of all drinks
         drink_array = ["Pictures/Drinks/Cuba_libre.jpg",
             "Pictures/Drinks/Gin_tonic.jpg",
             "Pictures/Drinks/Vodka_dzus.jpg"] % Vector of locations of drink pictures

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: end_prog
        function end_progButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: arrow_r
        function arrow_rButtonPushed(app, event)
                app.drink_array = circshift(app.drink_array, 1);
                app.drinks = circshift(app.drinks, 1);
                app.current_drink = app.drinks(1);
                app.drink_image.ImageSource = imread(app.drink_array(1));
                

        end

        % Button pushed function: arrow_l
        function arrow_lButtonPushed(app, event)
            app.drink_array = circshift(app.drink_array, -1);
                app.drinks = circshift(app.drinks, -1);
                app.current_drink = app.drinks(1);
                app.drink_image.ImageSource = imread(app.drink_array(1));
        end

        % Button pushed function: mix
        function mixButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Background_img.jpg');
            app.personalised = uibutton(app.UIFigure, 'push');
            app.personalised.Text = 'Vlastní';
            app.ruleta = uibutton(app.UIFigure, 'push');
            app.ruleta.Text = 'Ruleta';
            app.mix = uibutton(app.UIFigure, 'push');
            app.mix.ButtonPushedFcn = createCallbackFcn(app, @mixButtonPushed, true);
            app.mix.Text = 'Namíchat';
            app.end_prog = uibutton(app.UIFigure, 'push');
            app.end_prog.ButtonPushedFcn = createCallbackFcn(app, @end_progButtonPushed, true);
            app.end_prog.Text = 'Ukončit';
            app.arrow_l = uibutton(app.UIFigure, 'push');
            app.arrow_l.ButtonPushedFcn = createCallbackFcn(app, @arrow_lButtonPushed, true);
            app.arrow_l.Text = '<';
            app.arrow_r = uibutton(app.UIFigure, 'push');
            app.arrow_r.ButtonPushedFcn = createCallbackFcn(app, @arrow_rButtonPushed, true);
            app.arrow_r.Text = '>';
            app.drink_image = uiimage(app.UIFigure);
            app.drink_image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Drinks', 'Cuba_libre.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end